classdef The_Sotware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectFolderButton  matlab.ui.control.Button
        ShowFolderImage     matlab.ui.control.Button
        SelectImage         matlab.ui.control.Button
        PictureFrame        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selpath % stores path to folder
        pictures % images used to make mosaic
        baseImgPath % path to image to be mosaiced
    end
    

    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %disp("HEllo WOrld")
            app.selpath = uigetdir;
            app.selpath = app.selpath+"\";
        end

        % Button pushed function: ShowFolderImage
        function ShowFolderImagePushed(app, event)
            app.pictures = filesRead(app.selpath);
            imshow(app.pictures{3}, 'Parent', app.PictureFrame);
        end

        % Button pushed function: SelectImage
        function SelectImagePushed(app, event)
            [file, path] = uigetfile;
            figure(app.UIFigure);
            imshow(fullfile(path, file), 'Parent', app.PictureFrame);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ShowFolderImage = uibutton(app.UIFigure, 'push');
            app.ShowFolderImage.ButtonPushedFcn = createCallbackFcn(app, @ShowFolderImagePushed, true);
            app.ShowFolderImage.Text = 'Display 3rd Image';
            app.SelectImage = uibutton(app.UIFigure, 'push');
            app.SelectImage.ButtonPushedFcn = createCallbackFcn(app, @SelectImagePushed, true);
            app.SelectImage.Text = 'Select Image';
            app.PictureFrame = uiaxes(app.UIFigure);
            title(app.PictureFrame, '')
            app.PictureFrame.XTick = [];
            app.PictureFrame.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = The_Sotware

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end